classdef App_sing2midi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        TEMPOPanel                    matlab.ui.container.Panel
        TempobpmEditFieldLabel        matlab.ui.control.Label
        TempobpmEditField             matlab.ui.control.NumericEditField
        AceptarButton                 matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        MetrnomoButton                matlab.ui.control.Button
        PararMetrnomoButton           matlab.ui.control.Button
        AUDIOPanel                    matlab.ui.container.Panel
        GrabarPanel                   matlab.ui.container.Panel
        ParardeGrabarButton           matlab.ui.control.Button
        GrabarAudioButton             matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        PlayAudioButton               matlab.ui.control.Button
        StopAudioButton               matlab.ui.control.Button
        CargarPanel                   matlab.ui.container.Panel
        CargarAudioButton             matlab.ui.control.Button
        CONVERTIRAMIDIPanel           matlab.ui.container.Panel
        ConvertirButton               matlab.ui.control.Button
        PlayMIDIButton                matlab.ui.control.Button
        EXPORTARAARCHIVOMIDImidPanel  matlab.ui.container.Panel
        ExportarButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        recorder;
        bpm=120;
        x;
        Fs;
        audio;
        thz;
        hz;
        m;
        m2;
        t;
        filename;
        nmat;
        path;
        ofname;
        on=false; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TempobpmEditField
        function TempobpmEditFieldValueChanged(app, event)
            value = app.TempobpmEditField.Value;
            app.TempobpmEditField.Value =value;
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            app.bpm = app.TempobpmEditField.Value;
        end

        % Button pushed function: CargarAudioButton
        function CargarAudioButtonPushed(app, event)
            [app.x,app.Fs,app.t] = cargarAudio();
            plot(app.UIAxes,app.t,app.x);
        end

        % Button pushed function: ConvertirButton
        function ConvertirButtonPushed(app, event)
            if(isempty(app.x))
                msgbox('Error! Graba o carga un archivo de audio');
            else
                [app.nmat,app.m,app.thz,app.m2] = audio2midi(app.bpm,app.x,app.Fs);
                plot(app.UIAxes3,app.thz,app.m2);
                pianoroll2(app.UIAxes3,app.nmat,'sec');
            end

        end

        % Button pushed function: ExportarButton
        function ExportarButtonPushed(app, event)
            if(isempty(app.nmat))
                 msgbox('Error! Hay que convertir a MIDI primero');
            else
                 [app.ofname, app.path] = uiputfile('*.mid','Guardar archivo MIDI (.mid)','');
                 if app.ofname == 0
                    msgbox('No se ha guardado el archivo .mid','Error');
                    return
                 end
                 writemidi(app.nmat,app.ofname,app.bpm);
            end
        end

        % Button pushed function: PlayAudioButton
        function PlayAudioButtonPushed(app, event)
            if(isempty(app.x))
                 msgbox('Error! Hay que cargar o grabar un archivo de audio');
            else
                   app.audio = audioplayer(app.x,app.Fs);
                   play(app.audio)
            end
        end

        % Button pushed function: PlayMIDIButton
        function PlayMIDIButtonPushed(app, event)
            if(isempty(app.nmat))
                 msgbox('Error! Hay que convertir  MIDI primero');
            else
                playsound(app.nmat)
            end
        end

        % Button pushed function: GrabarAudioButton
        function GrabarAudioButtonPushed(app, event)
            [app.recorder,app.Fs] = grabarAudio();
        end

        % Button pushed function: ParardeGrabarButton
        function ParardeGrabarButtonPushed(app, event)
            app.on=false;
            if(isempty(app.recorder))
                 msgbox('Error');
            else
                [app.x,app.t] = pararGrabacion(app.recorder);
                plot(app.UIAxes,app.t,app.x);
                [app.filename, app.path] = uiputfile('*.mp3;*.wav','Guardar archivo de audio','');
                if app.filename == 0
                    app.filename = 'demo.wav';
                    msgbox('No se ha guardado el audio .wav','Error');
                    return
                end
                audiowrite(app.filename,app.x,app.Fs);
            end
        end

        % Button pushed function: MetrnomoButton
        function MetrnomoButtonPushed(app, event)
             bps = app.bpm/60;
             ts=8000;
             f=180;
             tiempo=0:1/ts:0.1;
             s=sin(2*pi*f*tiempo);
             segundos = (1/bps);
             app.on=true;
             while(app.on==true)
                sound(s)
                pause(segundos)
                if(app.on==false)
                    break;
                end
             end
        end

        % Button pushed function: PararMetrnomoButton
        function PararMetrnomoButtonPushed(app, event)
            app.on=false;
        end

        % Button pushed function: StopAudioButton
        function StopAudioButtonPushed(app, event)
            stop(app.audio)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal de audio')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Estimacion de tono')
            xlabel(app.UIAxes3, 'Tiempo (s)')
            ylabel(app.UIAxes3, 'Pitch')
            zlabel(app.UIAxes3, 'Z')
            app.TEMPOPanel = uipanel(app.UIFigure);
            app.TEMPOPanel.Title = 'TEMPO';
            app.TempobpmEditFieldLabel = uilabel(app.TEMPOPanel);
            app.TempobpmEditFieldLabel.Text = 'Tempo(bpm)';
            app.TempobpmEditField = uieditfield(app.TEMPOPanel, 'numeric');
            app.TempobpmEditField.ValueChangedFcn = createCallbackFcn(app, @TempobpmEditFieldValueChanged, true);
            app.TempobpmEditField.Value = 120;
            app.AceptarButton = uibutton(app.TEMPOPanel, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = {'Aceptar'; ''};
            app.Panel = uipanel(app.TEMPOPanel);
            app.MetrnomoButton = uibutton(app.Panel, 'push');
            app.MetrnomoButton.ButtonPushedFcn = createCallbackFcn(app, @MetrnomoButtonPushed, true);
            app.MetrnomoButton.Text = 'Metrónomo';
            app.PararMetrnomoButton = uibutton(app.Panel, 'push');
            app.PararMetrnomoButton.ButtonPushedFcn = createCallbackFcn(app, @PararMetrnomoButtonPushed, true);
            app.PararMetrnomoButton.Text = 'Parar Metrónomo';
            app.AUDIOPanel = uipanel(app.UIFigure);
            app.AUDIOPanel.Title = 'AUDIO';
            app.GrabarPanel = uipanel(app.AUDIOPanel);
            app.GrabarPanel.Title = 'Grabar';
            app.ParardeGrabarButton = uibutton(app.GrabarPanel, 'push');
            app.ParardeGrabarButton.ButtonPushedFcn = createCallbackFcn(app, @ParardeGrabarButtonPushed, true);
            app.ParardeGrabarButton.Text = 'Parar de Grabar';
            app.GrabarAudioButton = uibutton(app.GrabarPanel, 'push');
            app.GrabarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarAudioButtonPushed, true);
            app.GrabarAudioButton.Text = 'Grabar Audio';
            app.Panel_3 = uipanel(app.AUDIOPanel);
            app.PlayAudioButton = uibutton(app.Panel_3, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAudioButtonPushed, true);
            app.PlayAudioButton.Text = 'Play Audio';
            app.StopAudioButton = uibutton(app.Panel_3, 'push');
            app.StopAudioButton.ButtonPushedFcn = createCallbackFcn(app, @StopAudioButtonPushed, true);
            app.StopAudioButton.Text = 'Stop Audio';
            app.CargarPanel = uipanel(app.AUDIOPanel);
            app.CargarPanel.Title = 'Cargar';
            app.CargarAudioButton = uibutton(app.CargarPanel, 'push');
            app.CargarAudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargarAudioButtonPushed, true);
            app.CargarAudioButton.Text = 'Cargar Audio';
            app.CONVERTIRAMIDIPanel = uipanel(app.UIFigure);
            app.CONVERTIRAMIDIPanel.Title = 'CONVERTIR A MIDI';
            app.ConvertirButton = uibutton(app.CONVERTIRAMIDIPanel, 'push');
            app.ConvertirButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButtonPushed, true);
            app.ConvertirButton.Text = 'Convertir';
            app.PlayMIDIButton = uibutton(app.CONVERTIRAMIDIPanel, 'push');
            app.PlayMIDIButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMIDIButtonPushed, true);
            app.PlayMIDIButton.Text = 'Play MIDI';
            app.EXPORTARAARCHIVOMIDImidPanel = uipanel(app.UIFigure);
            app.EXPORTARAARCHIVOMIDImidPanel.Title = 'EXPORTAR A ARCHIVO MIDI (.mid)';
            app.ExportarButton = uibutton(app.EXPORTARAARCHIVOMIDImidPanel, 'push');
            app.ExportarButton.ButtonPushedFcn = createCallbackFcn(app, @ExportarButtonPushed, true);
            app.ExportarButton.Text = 'Exportar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_sing2midi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end